classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        Button       matlab.ui.control.Button
        EditField    matlab.ui.control.EditField
        Label        matlab.ui.control.Label
        Label_2      matlab.ui.control.Label
        Label_3      matlab.ui.control.Label
        Label_4      matlab.ui.control.Label
        EditField_2  matlab.ui.control.EditField
        EditField_3  matlab.ui.control.EditField
        xtLabel      matlab.ui.control.Label
        ytLabel      matlab.ui.control.Label
        ztLabel      matlab.ui.control.Label
        EditField_4  matlab.ui.control.EditField
        tLabel       matlab.ui.control.Label
        Label_5      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UIAxes.cla;  
            syms t;
            app.Label_5.Visible = false;
            try
            xm = str2sym(app.EditField.Value);
            ym = str2sym(app.EditField_2.Value);
            zm = str2sym(app.EditField_3.Value);
            t0 = str2double(app.EditField_4.Value);
            
            x0 = double(subs(xm,t,t0));
            y0 = double(subs(ym,t,t0));
            z0 = double(subs(zm,t,t0));
            
            fplot3(app.UIAxes,xm,ym,zm);
            hold(app.UIAxes,'on');
            grid(app.UIAxes,'on');
            plot3(app.UIAxes,x0,y0,z0,'*');

            x1 = subs(diff(xm,1,t),t,t0);
            y1 = subs(diff(ym,1,t),t,t0);
            z1 = subs(diff(zm,1,t),t,t0);
            
            x2 = subs(diff(xm,2,t),t,t0);
            y2 = subs(diff(ym,2,t),t,t0);
            z2 = subs(diff(zm,2,t),t,t0);
            
            x3 = subs(diff(xm,3,t),t,t0);
            y3 = subs(diff(ym,3,t),t,t0);
            z3 = subs(diff(zm,3,t),t,t0);
            syms x y z;
            
            sop = [x-x0 y-y0 z-z0;x1 y1 z1;x2 y2 z2];
            Sopric = simplify(det(sop));
            
            Normal = simplify((x-x0).*x1+(y-y0).*y1+(z-z0).*z1);
            
            spr = [x-x0 y-y0 z-z0;x1 y1 z1;det([y1 z1; y2 z2]) det([z1 x1;z2 x2]) det([x1 y1;x2 y2])];
            Spryam = simplify(det(spr));
           
            interval = [double(x0-1) double(x0+1) double(y0-1) double(y0+1) double(z0-1) double(z0+1)];
            fimplicit3(app.UIAxes,Sopric,interval,'EdgeColor','none','FaceAlpha',0.7);
            fimplicit3(app.UIAxes,Normal,interval,'EdgeColor','none','FaceAlpha',0.7);
            fimplicit3(app.UIAxes,Spryam,interval,'EdgeColor','none','FaceAlpha',0.7);
            
            
            kriv = double(simplify((((z2*y1-y2*z1)^2+(x2*z1-z2*x1)^2+(y2*x1-x2*y1)^2)^0.5)/((x1^2+y1^2+z1^2)^(3/2))));
            app.Label_2.Text = num2str(kriv);
            
            kruch = double(simplify((x3*(y1*z2-y2*z1)+y3*(x2*z1-x1*z2)+z3*(x1*y2-x2*y1))/((y1*z2-y2*z1)^2+(x2*z1-x1*z2)^2+(x1*y2-x2*y1)^2)));
            app.Label_4.Text = num2str(kruch);
            catch
                app.Label_5.Visible = true;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Построить';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Кривизна:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Кручение:';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.xtLabel = uilabel(app.UIFigure);
            app.xtLabel.Text = 'x(t)';
            app.ytLabel = uilabel(app.UIFigure);
            app.ytLabel.Text = 'y(t)';
            app.ztLabel = uilabel(app.UIFigure);
            app.ztLabel.Text = 'z(t)';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.tLabel = uilabel(app.UIFigure);
            app.tLabel.Text = 't';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Visible = 'off';
            app.Label_5.Text = 'Ошибка ввода!!!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end